classdef Volcano_rocks_simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RestartallButton               matlab.ui.control.Button
        CurrentpositionPanel           matlab.ui.container.Panel
        TimesincelaunchsecondsEditField  matlab.ui.control.NumericEditField
        TimesincelaunchsecondsEditFieldLabel  matlab.ui.control.Label
        HorizontaldistancemetersEditField  matlab.ui.control.NumericEditField
        HorizontaldistancemetersEditFieldLabel  matlab.ui.control.Label
        HeightmetersEditField          matlab.ui.control.NumericEditField
        HeightmetersEditFieldLabel     matlab.ui.control.Label
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        GraphtrajectoryButton          matlab.ui.control.Button
        InitialvariablesPanel          matlab.ui.container.Panel
        InitialheightmetersEditField   matlab.ui.control.NumericEditField
        InitialheightmetersEditFieldLabel  matlab.ui.control.Label
        ConsiderairresistanceCheckBox  matlab.ui.control.CheckBox
        LaunchangledegreesEditField    matlab.ui.control.NumericEditField
        LaunchangledegreesEditFieldLabel  matlab.ui.control.Label
        InitialvelocitymsEditField     matlab.ui.control.NumericEditField
        InitialvelocitymsEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Global variables to count number of trajectories 
        num_lineas = 0;

        % Matrix that stores x and y limits for better visualization
        max_y = []
        max_x = []
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraphtrajectoryButton
        function GraphtrajectoryButtonPushed(app, event)
            % Plus one trayectory to the count
            app.num_lineas = app.num_lineas + 1;
            
            % Check if air resistance is going to be considered
            if app.ConsiderairresistanceCheckBox.Value == 1 % With air resistance
                % Declaring variables
                delta_t = 0.1;
                t = 0:delta_t:500;
                g = 9.81;
                
                % Viscosity at 90°C times 6*pi
                b = 2.139 * 10^-5 * pi*6;
                e = 2.71828;
                
                % Getting GUI data
                angulo = app.LaunchangledegreesEditField.Value;
                vel = app.InitialvelocitymsEditField.Value;
                
                % Calculate derivatives of "x" and "y" for all time t
                yp2 = (g/b + sind(angulo).*vel) .* e.^(-b .* t) - (g/b);
                xp2 = cosd(angulo).*vel .* e.^(-b.*t);
                
                % Counter to access matrixes
                cont = 2;
                
                % Creating matrixes to store values
                y2 = zeros(size(t));
                x2 = zeros(size(t));
                
                % Get starting position
                y2(1) = app.InitialheightmetersEditField.Value;
                x2(1) = 0;
                
                % Euler's method is used to calculate its position at every
                % time
                for j = 0.5:delta_t:(10^5)
                    y2(cont) = y2(cont-1) + yp2(cont-1) * delta_t; 
                    x2(cont) = x2(cont-1) + xp2(cont-1) * delta_t; 
                
                    % Stops calculating values when height is negative
                    if y2(cont) < 0
                        break;
                    end
                    cont = cont + 1;
                end
                
                % Creating the animated lines used to graph rocks' trajectories
                con_friccion = animatedline(app.UIAxes, "Color", [rand rand rand], "LineStyle", "-.");
                
                % Stablish x and y limits
                app.max_y = [app.max_y max(y2)];
                ylim(app.UIAxes, [0, max(app.max_y)])

                app.max_x = [app.max_x x2(cont)];
                xlim(app.UIAxes, [0, max(app.max_x)])
    
                % Creating a loop to graph point by point so it looks like
                % an animation
                for cont2 = 1:cont
                    addpoints(con_friccion, x2(cont2), y2(cont2));
                    app.HeightmetersEditField.Value = y2(cont2);
                    app.HorizontaldistancemetersEditField.Value = x2(cont2);
                    app.TimesincelaunchsecondsEditField.Value = cont2;
                    drawnow
                end
                app.HeightmetersEditField.Value = 0;
                
                % Show new results along with old results
                resultados{1} = sprintf('Trajectory #%d - With air resistance', app.num_lineas);
                resultados{2} = sprintf('Final distance: %.3f meters', x2(cont2));
                resultados{3} = sprintf('Max height: %.3f meters', max(y2));
                resultados{4} = sprintf('Time in the air: %d seconds\n', cont);
                resultados{5} = '';
                
                if strcmp(app.ResultsTextArea.Value , '')
                    app.ResultsTextArea.Value = resultados;
                else
                    app.ResultsTextArea.Value = [app.ResultsTextArea.Value(:)', resultados];
                end

                % Creating legend
                leyenda = 1:app.num_lineas;
                leyenda = arrayfun(@(x) strcat("Trajectory #", num2str(x)), leyenda);
                legend(app.UIAxes, leyenda, "AutoUpdate", "off", "Location", "best")
            
            
            else % When not considering air resistance
                % Declaring variables
                delta_t = 0.1;
                t = 0:delta_t:500;
                
                % Getting GUI data
                angulo = app.LaunchangledegreesEditField.Value;
                vel = app.InitialvelocitymsEditField.Value;
                
                % Calculate derivatives of "x" and "y" for all time t
                yp1 = (sind( angulo ) * vel) - (9.81 * t);
                xp1 = cosd(angulo).*vel .* ones(size(t));
                
                % Counter to access matrixes
                cont = 2;
                
                % Creating matrixes to store values
                y1 = zeros(size(t));
                x1 = zeros(size(t));
                
                % Get starting position
                y1(1) = app.InitialheightmetersEditField.Value;
                x1(1) = 0;
                
                % Euler's method is used to calculate its position at every
                % time
                for j = 0.5:delta_t:(10^5)
                    y1(cont) = y1(cont-1) + yp1(cont-1) * delta_t; 
                    x1(cont) = x1(cont-1) + xp1(cont-1) * delta_t; 
                
                    % Stops calculating values when height is negative
                    if y1(cont) < 0
                        break;
                    end
                    cont = cont + 1;
                end
                
                % Creating the animated lines used to graph rocks' trajectories
                sin_friccion = animatedline(app.UIAxes, "Color", [randi(7)/10 randi(7)/10 randi(7)/10], "LineStyle", "--");
                
                % Stablish x and y limits
                app.max_y = [app.max_y max(y1)];
                ylim(app.UIAxes, [0, max(app.max_y)])

                app.max_x = [app.max_x x1(cont)];
                xlim(app.UIAxes, [0, max(app.max_x)])
    
                % Creating a loop to graph point by point so it looks like
                % an animation
                for cont2 = 1:cont
                    addpoints(sin_friccion, x1(cont2), y1(cont2));
                    app.HeightmetersEditField.Value = y1(cont2);
                    app.HorizontaldistancemetersEditField.Value = x1(cont2);
                    app.TimesincelaunchsecondsEditField.Value = cont2;
                    drawnow
                end
                app.HeightmetersEditField.Value = 0;
                
                % Show new results along with old results
                resultados{1} = sprintf('Trajectory #%d - Without air resistance', app.num_lineas);
                resultados{2} = sprintf('Final distance: %.3f meters', x1(cont2));
                resultados{3} = sprintf('Max height: %.3f meters', max(y1));
                resultados{4} = sprintf('Time in the air: %d seconds', cont);
                resultados{5} = '';
                
                if strcmp(app.ResultsTextArea.Value , '')
                    app.ResultsTextArea.Value = resultados;
                else
                    app.ResultsTextArea.Value = [app.ResultsTextArea.Value(:)', resultados];
                end

                % Creating legend
                leyenda = 1:app.num_lineas;
                leyenda = arrayfun(@(x) strcat("Trajectory #", num2str(x)), leyenda);
                legend(app.UIAxes, leyenda, "AutoUpdate", "off", "Location", "best")
            end
        end

        % Value changed function: HeightmetersEditField
        function HeightmetersEditFieldValueChanged(app, event)

        end

        % Button pushed function: RestartallButton
        function RestartallButtonPushed(app, event)
            % Erase what was previously graphed
            cla(app.UIAxes);

            % Restart important varibles
            app.num_lineas = 0;
            app.max_x = [];
            app.max_y = [];
            
            % Erase text in text box
            app.ResultsTextArea.Value = {''};
        end

        % Value changed function: InitialheightmetersEditField
        function InitialheightmetersEditFieldValueChanged(app, event)
            app.HeightmetersEditField.Value = app.InitialheightmetersEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Parabolic trajectory')
            xlabel(app.UIAxes, 'Horizontal distance (meters)')
            ylabel(app.UIAxes, 'Height (meters)')
            zlabel(app.UIAxes, 'Z')
            app.InitialvariablesPanel = uipanel(app.UIFigure);
            app.InitialvariablesPanel.Title = 'Initial variables';
            app.InitialvelocitymsEditFieldLabel = uilabel(app.InitialvariablesPanel);
            app.InitialvelocitymsEditFieldLabel.Text = 'Initial velocity (m/s)';
            app.InitialvelocitymsEditField = uieditfield(app.InitialvariablesPanel, 'numeric');
            app.InitialvelocitymsEditField.Value = 300;
            app.LaunchangledegreesEditFieldLabel = uilabel(app.InitialvariablesPanel);
            app.LaunchangledegreesEditFieldLabel.Text = 'Launch angle (degrees)';
            app.LaunchangledegreesEditField = uieditfield(app.InitialvariablesPanel, 'numeric');
            app.LaunchangledegreesEditField.Value = 30;
            app.ConsiderairresistanceCheckBox = uicheckbox(app.InitialvariablesPanel);
            app.ConsiderairresistanceCheckBox.Text = 'Consider air resistance?';
            app.InitialheightmetersEditFieldLabel = uilabel(app.InitialvariablesPanel);
            app.InitialheightmetersEditFieldLabel.Text = 'Initial height (meters)';
            app.InitialheightmetersEditField = uieditfield(app.InitialvariablesPanel, 'numeric');
            app.InitialheightmetersEditField.ValueChangedFcn = createCallbackFcn(app, @InitialheightmetersEditFieldValueChanged, true);
            app.InitialheightmetersEditField.Value = 2929;
            app.GraphtrajectoryButton = uibutton(app.UIFigure, 'push');
            app.GraphtrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GraphtrajectoryButtonPushed, true);
            app.GraphtrajectoryButton.Text = 'Graph trajectory';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.ResultsTextArea.Editable = 'off';
            app.CurrentpositionPanel = uipanel(app.UIFigure);
            app.CurrentpositionPanel.Title = 'Current position';
            app.HeightmetersEditFieldLabel = uilabel(app.CurrentpositionPanel);
            app.HeightmetersEditFieldLabel.Text = 'Height (meters)';
            app.HeightmetersEditField = uieditfield(app.CurrentpositionPanel, 'numeric');
            app.HeightmetersEditField.ValueDisplayFormat = '%7.2f';
            app.HeightmetersEditField.ValueChangedFcn = createCallbackFcn(app, @HeightmetersEditFieldValueChanged, true);
            app.HeightmetersEditField.Editable = 'off';
            app.HeightmetersEditField.Value = 2929;
            app.HorizontaldistancemetersEditFieldLabel = uilabel(app.CurrentpositionPanel);
            app.HorizontaldistancemetersEditFieldLabel.Text = 'Horizontal distance (meters)';
            app.HorizontaldistancemetersEditField = uieditfield(app.CurrentpositionPanel, 'numeric');
            app.HorizontaldistancemetersEditField.ValueDisplayFormat = '%7.2f';
            app.HorizontaldistancemetersEditField.Editable = 'off';
            app.TimesincelaunchsecondsEditFieldLabel = uilabel(app.CurrentpositionPanel);
            app.TimesincelaunchsecondsEditFieldLabel.Text = 'Time since launch (seconds)';
            app.TimesincelaunchsecondsEditField = uieditfield(app.CurrentpositionPanel, 'numeric');
            app.TimesincelaunchsecondsEditField.ValueDisplayFormat = '%.0f';
            app.TimesincelaunchsecondsEditField.Editable = 'off';
            app.RestartallButton = uibutton(app.UIFigure, 'push');
            app.RestartallButton.ButtonPushedFcn = createCallbackFcn(app, @RestartallButtonPushed, true);
            app.RestartallButton.Text = 'Restart all';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Volcano_rocks_simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end