classdef car_simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SimulacionEnProgresoEditField  matlab.ui.control.EditField
        LoadingEditField               matlab.ui.control.EditField
        LoadingEditFieldLabel          matlab.ui.control.Label
        EmergenciaLampLight            matlab.ui.control.Lamp
        EmergenciaenprocesoLabel       matlab.ui.control.Label
        RestartsimulationButton        matlab.ui.control.Button
        DatafromcurrentpositionPanel   matlab.ui.container.Panel
        yLabel                         matlab.ui.control.Label
        xLabel                         matlab.ui.control.Label
        PosicionYEditField             matlab.ui.control.NumericEditField
        TiempoTotalEditField           matlab.ui.control.NumericEditField
        TimesincestartsecondsLabel     matlab.ui.control.Label
        PerdidaDeCalorEditField        matlab.ui.control.NumericEditField
        HeatlossJLabel                 matlab.ui.control.Label
        PosicionXEditField             matlab.ui.control.NumericEditField
        PositionmLabel                 matlab.ui.control.Label
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        BeginsimulationButton          matlab.ui.control.Button
        InitialvariablesPanel          matlab.ui.container.Panel
        InitialvelocitymsEditField     matlab.ui.control.NumericEditField
        InitialvelocitymsEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Global variables:
        num_trayectorias = 0;
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BeginsimulationButton
        function BeginsimulationButtonPushed(app, event)
            
            %% Event is triggered
            app.num_trayectorias = app.num_trayectorias + 1;
            evento_derrape = 0;

            % Everything resets in case the user didn't do it
            app.RestartsimulationButtonPushed(app);

            % Buttons become disabled to prevent errors
            app.BeginsimulationButton.Enable = "off";
            app.RestartsimulationButton.Enable = "off";
            app.SimulacionEnProgresoEditField.Visible = "on";
            
            %% Cubic equation is created to model the racing track
            
            % Initial points that are given to us
            x_inicial = 100;
            y_inicial = 1700;
            x_final = 2800;
            y_final = 200;
            
            % Chosen points to create the equation (4 were needed)
            x3 = 1448.3;
            y3 = 1440.59;
            
            x4 = 1713.81;
            y4 = 666.27;
            
            
            % Finding the coefficients of the cubic equation
            x = [100, 2800, x3, x4];
            y = [1700; 200; y3; y4];
            
            A = [x(1)^3, x(1)^2, x(1), 1;
                 x(2)^3, x(2)^2, x(2), 1;
                 x(3)^3, x(3)^2, x(3), 1;
                 x(4)^3, x(4)^2, x(4), 1;];
            
            coef = inv(A) * y;
            
            syms x;
            f = coef(1).*x.^3 + coef(2).*x.^2 + coef(3).*x + coef(4);
            fun_an = @(a) coef(1).*a.^3 + coef(2).*a.^2 + coef(3).*a + coef(4);
            fun_derivative1_an = @(a) 3.*coef(1).*a.^2 + 2.*coef(2).*a + coef(3);
            fun_derivative2_an = @(a) 6.*coef(1).*a + 2.*coef(2);
            
            %% Declaring important variables and initial conditions
            vel = app.InitialvelocitymsEditField.Value;
            t = 0.1;
            d = t * vel;
            masa = 878;
            g = 9.81;
            
            % Creating part of the equation to calculate the arc length
            dist_f = @(a) sqrt(1 + fun_derivative1_an(a).^2);
            
            xi = 100;
            hold(app.UIAxes, "on")

            % Making graphs
            x = 100:1:2800;
            plot(app.UIAxes, x, subs(f), 'r-', 'DisplayName','Race track')
            drawnow

            %% Creating the grandstands (represented by lines)
            coef2 = [coef(1)*3, coef(2)*2, coef(3)];
            zeros2 = roots(coef2);
            
            % x-coordenates where the grandstands are inserted
            x_gradas = [min(zeros2)-100, max(zeros2)-100];
            
            % The x-coordenate is changed if its exactly in the minimum
            % point or maximum point, for mathematical reasons (tangent line)
            if any(x_gradas == zeros2(1)) || any(x_gradas == zeros2(2))
                x_gradas(x_gradas == zeros2(1)) = x_gradas(x_gradas == zeros2(1)) + 0.01;
                x_gradas(x_gradas == zeros2(2)) = x_gradas(x_gradas == zeros2(2)) + 0.01;
            end
            
            % Tangent line equation is created and graphed for 80 meters
            yi = fun_an(x_gradas);
            
            m = fun_derivative1_an(x_gradas);
            real_b = yi - m .* x_gradas;
            
            tamano_gradas = 80;
            dx = sqrt((tamano_gradas).^2 ./ (m.^2 + 1));
            
            
            % We find the equation of the line perpendicular to the
            % tangent, and the calculate the distance in x and y that we
            % need to move the tangent to place it 20m away from the track
            m_inv = -1 ./ m;
            dx_perp = sqrt(( 20 ).^2 ./ (m_inv.^2 + 1));
            
            % since m = dy/dx
            dy_perp = m_inv .* dx_perp;
            
            % Graphing each line separately using a for loop
            
            for i = 1:max(size(x_gradas))
                tan_line = @(x) m(i) .* (x + dx_perp(i)) + real_b(i) - dy_perp(i);
                axis2 = linspace(x_gradas(i) - dx(i)/2 - dx_perp(i), x_gradas(i) + dx(i)/2 - dx_perp(i), 2);
                plot(app.UIAxes, axis2, tan_line(axis2), "k-", "LineWidth", 0.5,'DisplayName','Grandstands')
            end
            
            
            %% Data preprocessing 

            % Finding max. velocity on critical points

            radio_curvatura_actual = (1 + (fun_derivative1_an(min(zeros2))).^2 ).^(3./2) ./ ( abs(fun_derivative2_an(min(zeros2))) );
            vel_max_zero = ( (1.79.*radio_curvatura_actual.*(masa.*g + 5.*g)) ./ masa ).^(1/2);
            vel_max_zero = double(vel_max_zero);


            X = zeros(size(100:d:2800));
            X(1) = x_inicial;

            i = 2;
            
            % Showing loading percentage
            app.LoadingEditField.Visible = "on";
            app.LoadingEditField.Enable = "on";
            app.LoadingEditFieldLabel.Visible = "on";
            app.LoadingEditFieldLabel.Enable = "on";

            while xi < x_final

                % Equation to find max. velocity on point xi
                radio_curvatura_actual = ( (1 + (fun_derivative1_an(xi).^2 )).^(3./2)) ./ abs(fun_derivative2_an(xi));
                vel_max_actual = ( (1.79.*radio_curvatura_actual.*(masa.*g + 5.*g)) ./ masa ).^(1/2);
                vel_max_actual = double(vel_max_actual);

                % Checking if current velocity is larger than max. velocity
                if vel_max_actual < vel
                    evento_derrape = 1;
                    break;
                end
                if xi > min(zeros2)
                    if vel_max_zero < vel
                        evento_derrape = 1;
                        break;
                    end
                end

                % xf solving
                eq_dis = @(xf) integral(dist_f, xi, xf) - d;
                X(i) = fzero(eq_dis, xi+20);
                
                % new xi
                xi = X(i);
                i = i + 1;

                % Calculating how much of the track has been loaded
                if mod(i, 2) == 0
                    texto = sprintf("%3.2f", xi/x_final*100);
                    if xi/x_final > 1
                        texto = "100";
                    end
                    app.LoadingEditField.Value = texto + "%";
                    drawnow
                    
                end

            end
            app.LoadingEditField.Visible = "off";
            app.LoadingEditField.Enable = "off";
            app.LoadingEditFieldLabel.Visible = "off";
            app.LoadingEditFieldLabel.Enable = "off";
            
            x = X;
            Y = subs(f);
            
            %% Making plot update!
            p = plot(app.UIAxes, X(1), Y(1), 's', 'MarkerFaceColor', 'cyan', 'DisplayName','Car');
            legend(app.UIAxes, "Location", "best", "AutoUpdate","off")
            drawnow
            pause(t)
            
            for k = 2:length(X)
                tic
                if X(k) ~= 0
                    p.XData = X(k);
                    p.YData = Y(k);
                    app.PosicionXEditField.Value = X(k);
                    app.PosicionYEditField.Value = double(Y(k));
                    app.TiempoTotalEditField.Value = app.TiempoTotalEditField.Value + t;
                    drawnow
                    
                    if toc < t - (t * 0.1)
                        pause(t-toc)
                    end
                    final_k = k;
                end
            end


            %% This happens if the car skids (car loses control)
            if evento_derrape == 1
                app.EmergenciaLampLight.Color = "r";
                red_color = app.EmergenciaLampLight.Color;
                app.EmergenciaLampLight.Visible = "on";
                app.EmergenciaLampLight.Enable = "on";
                app.EmergenciaenprocesoLabel.Visible = "on";
                app.EmergenciaenprocesoLabel.Enable = "on";

                % Making light blink
                pause(0.4)
                app.EmergenciaLampLight.Color = "k";
                pause(0.4)
                app.EmergenciaLampLight.Color = "r";
                pause(0.4)
                app.EmergenciaLampLight.Color = "k";
                pause(0.4)
                app.EmergenciaLampLight.Color = "r";

                %% Slow zoom in
                x = 100:2:2800;
                all_Y = subs(f);
                
                x_lim = [x_inicial-100, x_final+100];
                y_lim = [min(all_Y)-100, max(all_Y)+100];
                y_lim = double(y_lim);
                
                x_lim(2) = x_lim(2) * 4/5;
                y_lim(1) = y_lim(1) * (3/5);
                xlim(app.UIAxes, x_lim)
                ylim(app.UIAxes, y_lim)
                drawnow
                pause(0.4)
                x_lim(2) = x_lim(2) * 2/5;
                y_lim(1) = y_lim(1) + 800;
                app.PerdidaDeCalorEditField.Value = y_lim(1);
                xlim(app.UIAxes, x_lim)
                ylim(app.UIAxes, y_lim)
                drawnow
                pause(0.4)


                %% Calculations of the tangent line
                tamano_zona_derrape = 20;
                zona_critica = X(final_k);
                
                % Finding its coordenates (xi,yi)
                x = X(final_k);
                yi = subs(f);
                
                % Finding the m and b variables of the tangent y=mx+b
                m = subs(diff(f));
                real_b = yi - m .* zona_critica;
                
                % Finding delta x to graph the tangent 20m
                dx = sqrt((tamano_zona_derrape).^2 ./ (m.^2 + 1));
                
                % Creating tangent line equation
                tan_line = @(p) m.*p + real_b;
                
                % Graphing the tangent for 20m
                axis2 = linspace(zona_critica, zona_critica + dx, 2);
                plot(app.UIAxes, axis2, tan_line(axis2), "b--", "LineWidth", 1.5)

                axis2 = double(axis2);
                xlim(app.UIAxes, [min(axis2)-3, max(axis2)+3]);
                
                yaxis = double(tan_line(axis2));
                ylim(app.UIAxes, [min(yaxis)-3, max(yaxis)+3]);

                %% Make the car move slow on the tangent path
                t = 0.005;
                d = t * vel;
                
                X = zeros(size(xi:d:max(axis2)));
                i = 1;

                % Processing of tan line trayectory
                while xi < max(axis2)
                    % xf solving
                    X(i) = sqrt((d).^2 ./ (m.^2 + 1)) + xi;

                    % new xi
                    xi = X(i);
                    i = i + 1;
                end
                Y = tan_line(X);

                % Heat generated per d distance
                calor_por_d = 0.80.*(masa.*g + 5.*g) .* d;

                % Making plot update!
                for k = 1:length(X)
                    tic
                    p.XData = X(k);
                    p.YData = Y(k);
                    app.PosicionXEditField.Value = X(k);
                    app.PosicionYEditField.Value = double(Y(k));
                    app.TiempoTotalEditField.Value = app.TiempoTotalEditField.Value + t;
                    app.PerdidaDeCalorEditField.Value = app.PerdidaDeCalorEditField.Value + calor_por_d;
                    if mod(k, 5) == 0
                        if app.EmergenciaLampLight.Color == red_color
                            app.EmergenciaLampLight.Color = "k";
                        else
                            app.EmergenciaLampLight.Color = "r";
                        end
                    end
                    drawnow
                    pause(t)
                end

            end
            legend(app.UIAxes, "location", "best");
                     
            %% Printing results
            
            % Creating new results and showing them with the old results
            resultados{1} = sprintf('Simulation #%d - Velocity: %3.3f m/s', app.num_trayectorias, vel);
            resultados{2} = sprintf('Final position: x = %.3f meters, y = %.3f meters', p.XData, double(p.YData));
            if evento_derrape == 1
                resultados{3} = sprintf('Results: The car skidded and got out of the track');
                resultados{4} = sprintf('Heat caused: %3.3f Joules\n', app.PerdidaDeCalorEditField.Value);
            else
                resultados{3} = sprintf('Results: There was no skidding, car stayed on track');
                resultados{4} = sprintf('Heat caused: Negligible because no skidding');
            end
            resultados{5} = sprintf('Total time: %.2f seconds', double(app.TiempoTotalEditField.Value));
            resultados{6} = '';
            
            if strcmp(app.ResultsTextArea.Value , '')
                app.ResultsTextArea.Value = resultados;
            else
                app.ResultsTextArea.Value = [app.ResultsTextArea.Value(:)', resultados];
            end

            % Buttons stop being disabled
            app.BeginsimulationButton.Enable = "on";
            app.RestartsimulationButton.Enable = "on";
            app.SimulacionEnProgresoEditField.Visible = "off";

        end

        % Value changed function: PosicionXEditField
        function PosicionXEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: RestartsimulationButton
        function RestartsimulationButtonPushed(app, event)
            % Erase previous graphs
            cla(app.UIAxes);
            legend(app.UIAxes)
            delete(legend(app.UIAxes))

            % Restart axis
            axis(app.UIAxes, "auto xy");

            % Restart important variables
            app.PosicionXEditField.Value = 100;
            app.PosicionYEditField.Value = 1700;
            app.PerdidaDeCalorEditField.Value = 0;
            app.TiempoTotalEditField.Value = 0;
            
            % Make the Alarm button disappear 
            app.EmergenciaLampLight.Color = "k";
            app.EmergenciaLampLight.Visible = "off";
            app.EmergenciaLampLight.Enable = "off";
            app.EmergenciaenprocesoLabel.Visible = "off";
            app.EmergenciaenprocesoLabel.Enable = "off";
            
        end

        % Callback function
        function AlturainicialmetrosEditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Car simulation')
            xlabel(app.UIAxes, 'x position (meters)')
            ylabel(app.UIAxes, 'y position (meters)')
            zlabel(app.UIAxes, 'Z')
            app.InitialvariablesPanel = uipanel(app.UIFigure);
            app.InitialvariablesPanel.Title = 'Initial variables';
            app.InitialvelocitymsEditFieldLabel = uilabel(app.InitialvariablesPanel);
            app.InitialvelocitymsEditFieldLabel.Text = 'Initial velocity (m/s)';
            app.InitialvelocitymsEditField = uieditfield(app.InitialvariablesPanel, 'numeric');
            app.InitialvelocitymsEditField.Value = 51;
            app.BeginsimulationButton = uibutton(app.UIFigure, 'push');
            app.BeginsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @BeginsimulationButtonPushed, true);
            app.BeginsimulationButton.Text = 'Begin simulation';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.ResultsTextArea.Editable = 'off';
            app.DatafromcurrentpositionPanel = uipanel(app.UIFigure);
            app.DatafromcurrentpositionPanel.Title = 'Data from current position';
            app.PositionmLabel = uilabel(app.DatafromcurrentpositionPanel);
            app.PositionmLabel.Text = 'Position (m)';
            app.PosicionXEditField = uieditfield(app.DatafromcurrentpositionPanel, 'numeric');
            app.PosicionXEditField.ValueDisplayFormat = '%7.0f';
            app.PosicionXEditField.ValueChangedFcn = createCallbackFcn(app, @PosicionXEditFieldValueChanged, true);
            app.PosicionXEditField.Editable = 'off';
            app.PosicionXEditField.Value = 100;
            app.HeatlossJLabel = uilabel(app.DatafromcurrentpositionPanel);
            app.HeatlossJLabel.Text = 'Heat loss (J)';
            app.PerdidaDeCalorEditField = uieditfield(app.DatafromcurrentpositionPanel, 'numeric');
            app.PerdidaDeCalorEditField.ValueDisplayFormat = '%7.2f';
            app.PerdidaDeCalorEditField.Editable = 'off';
            app.TimesincestartsecondsLabel = uilabel(app.DatafromcurrentpositionPanel);
            app.TimesincestartsecondsLabel.Text = 'Time since start (seconds)';
            app.TiempoTotalEditField = uieditfield(app.DatafromcurrentpositionPanel, 'numeric');
            app.TiempoTotalEditField.ValueDisplayFormat = '%.2f';
            app.TiempoTotalEditField.Editable = 'off';
            app.PosicionYEditField = uieditfield(app.DatafromcurrentpositionPanel, 'numeric');
            app.PosicionYEditField.Editable = 'off';
            app.PosicionYEditField.Value = 1700;
            app.xLabel = uilabel(app.DatafromcurrentpositionPanel);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.DatafromcurrentpositionPanel);
            app.yLabel.Text = 'y';
            app.RestartsimulationButton = uibutton(app.UIFigure, 'push');
            app.RestartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RestartsimulationButtonPushed, true);
            app.RestartsimulationButton.Text = 'Restart simulation';
            app.EmergenciaenprocesoLabel = uilabel(app.UIFigure);
            app.EmergenciaenprocesoLabel.Enable = 'off';
            app.EmergenciaenprocesoLabel.Visible = 'off';
            app.EmergenciaenprocesoLabel.Text = {'Emergency'; 'in progress'};
            app.EmergenciaLampLight = uilamp(app.UIFigure);
            app.EmergenciaLampLight.Enable = 'off';
            app.EmergenciaLampLight.Visible = 'off';
            app.LoadingEditFieldLabel = uilabel(app.UIFigure);
            app.LoadingEditFieldLabel.Enable = 'off';
            app.LoadingEditFieldLabel.Visible = 'off';
            app.LoadingEditFieldLabel.Text = 'Loading %';
            app.LoadingEditField = uieditfield(app.UIFigure, 'text');
            app.LoadingEditField.Editable = 'off';
            app.LoadingEditField.Enable = 'off';
            app.LoadingEditField.Visible = 'off';
            app.LoadingEditField.Value = '0%';
            app.SimulacionEnProgresoEditField = uieditfield(app.UIFigure, 'text');
            app.SimulacionEnProgresoEditField.Editable = 'off';
            app.SimulacionEnProgresoEditField.Visible = 'off';
            app.SimulacionEnProgresoEditField.Value = 'Simulation in progress...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = car_simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end